classdef DCI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DCILabel                       matlab.ui.control.Label
        AlphaAngleSliderLabel          matlab.ui.control.Label
        OutputTextAreaLabel            matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        AlphaAngleEditFieldLabel       matlab.ui.control.Label
        EnergyEditField                matlab.ui.control.NumericEditField
        EnergyEditFieldLabel           matlab.ui.control.Label
        ConvexCrystal2DspacingEditField  matlab.ui.control.NumericEditField
        ConvexOrderEditField           matlab.ui.control.NumericEditField
        ConvexOrderEditFieldLabel      matlab.ui.control.Label
        ConcaveCrystal2DspacingEditField  matlab.ui.control.NumericEditField
        ConcaveOrderEditField          matlab.ui.control.NumericEditField
        ConcaveCrystalRadiusEditField  matlab.ui.control.NumericEditField
        AlphaAngleEditField            matlab.ui.control.NumericEditField
        AlphaAngleSlider               matlab.ui.control.Slider
        PlotButton                     matlab.ui.control.Button
        Lamp                           matlab.ui.control.Lamp
        OutputTextArea                 matlab.ui.control.TextArea
        ConcaveOrderEditFieldLabel     matlab.ui.control.Label
        ConvexCrystal2DspacingEditFieldLabel  matlab.ui.control.Label
        ConcaveCrystalRadiusEditFieldLabel  matlab.ui.control.Label
        ConcaveCrystal2DspacingEditFieldLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        energy
        vex2D
        vexorder
        cave2D
        caveorder
        rad2
        a
        % Description
    end
    
    methods (Access = private)
        
        function results = calc(app)
            cla(app.UIAxes)
            app.OutputTextArea.Value = '';

            E = app.energy;

            twods = double([app.vex2D/app.vexorder, app.cave2D/app.caveorder]);
            R2  = app.rad2;
            alpha = app.a;

            conv1 = 12.398419737d0;                       % NIST (National Institute of Standards and Technology)
            conv2 = pi()/180.0d0;                         % radians to degrees

            %% Program starts here
            % =======================================================================
            d2s = twods;                % 2d (crystal spacing)
            lam = conv1/E;              % lambda (wavelength)
            thets = asin(lam./d2s);     % Bragg angles in radians
            thetas = thets./conv2;      % Bragg angles in degree
            
            
            Mag = -cos( 2.0*thets(1,2) - alpha ) / cos( 2.0*thets(1,1) + alpha);   % Magnification : distance from source to detector divided by distance from source to crystal
            
%             disp(" ")
%             disp(" ")
%             disp(" ")
%             disp("thetas = " + thetas)
%             disp(" ")
            app.OutputTextArea.Value{1} = sprintf('%s\n%s', "thetas = " + thetas,"Magnification factor for concave-convex = " + Mag);
            app.OutputTextArea.Value{2} = sprintf('%s\n%s', "Magnification factor for concave-convex = " + Mag);
%             disp(" ")
%             disp(" ")
            app.OutputTextArea.Value{3} = sprintf('%s\n%s', "________________________");


            %% Concave Crystal_C2
            % =======================================================================
            theta2 = thetas(1,2);
            thet2 = theta2*conv2;
            RT = R2*cos(thet2);		% RT=radius of tangency circle
            xC2 = R2*sin(thet2);	% x-coordinate for center of concave crystal_C2
            yC2 = RT;				% y-coordinate for center of concave crystal_C2
            
            findgen75 = vpa( int64(0):int64(74) );      % makes a 75 element array
            findgen201 = vpa( int64(0):int64(200) );    % makes a 201 element array
            
            ph20 = (90.0d0 - theta2)*conv2;		              % polar angle for central point on crystal_C2
            ph2 = (-37.0d0/R2) + (findgen75/R2) + ph20;       % polar angles for 75 points in 1mm-steps along crystal_C2 surface
            ph22 = (-100.0d0/R2) + (findgen201/R2) + ph20;    % polar angles for 200 points in 1mm-steps of crystal_C2 sphere
            
            %{
            Cartesian coordinates (x22,y22) for 200 points of the crystal_C2 sphere
            and (x2,y2) for 75 points along the Si-533 crystal_C2 surface.
            The origin of the Cartesian system is at the center of tangency circle!
            %}
            x22 = R2*cos(ph22);
            y22 = R2*sin(ph22);
            
            x2 = R2*cos(ph2);			
            y2 = R2*sin(ph2);
            
            %% Convex Crystal_C1
            % =======================================================================
            theta1 = thetas(1,1);
            thet1 = theta1*conv2;
            R1 = RT/cos(thet1);
            xC1 = R1*sin(thet1);	% Cartesian coordinates for center of crystal_C1
            yC1 = RT;
            
            ph10 = atan(yC1/xC1);		                    % polar angle for center of crystal_C1
            ph1 = (-37.0/R1) + (findgen75/R1) + ph10;       % polar angles for 75 points in 1mm-steps along crystal_C1 surface
            ph11 = (-100.0/R1) + (findgen201/R1) + ph10;    % polar angles for 200 points in 1mm-steps of crystal_C1 sphere
            
            % Cartesian coordinates (x11,y11) for 200 points of the crystal_C1 sphere
            % and (x1,y1) for 75 points along the HOPG crystal_C2 surface.
            % ======================================================================
            x11 = R1*cos(ph11);
            y11 = R1*sin(ph11);
            
            x1 = R1*cos(ph1);			
            y1 = R1*sin(ph1);
            
            %%
            % Cartesian coordinates for points on tangency circle
            % ===================================================
            findgen2001 = vpa( int64(0):int64(2000) );
            xt = -RT + findgen2001*RT/1000;
            yt = sqrt(RT.^2-xt.^2);
            
            % Cartesian coordinates for the Point-Source S
            % ============================================
            xS = 0.0;
            yS = RT - R2*sin(thet2)*tan(2*ph20);
            
            % Cartesian coordinates for the image point I of the Point-Source S
            % =================================================================
            
            xI = 0.0;
            yI = RT + R1*sin(thet1)*tan(2*thet1);
            
%             disp(" ")
%             disp(" ")
%             disp(" ")
%             disp("Parameters: ")
%             disp("========================")
%             disp("Cu_k_alpha line")
%             disp("E(keV) = " + E)
%             disp(" ")
%             disp("Concave Si-533 Crystal_2")
%             disp("R2 = " + R2)
%             disp(" ")
%             disp("Convex Si-422 Crystal_1")
%             disp("R1 = " + R1)
%             disp(" ")
%             disp("Radius of Tangency Circle")
%             disp("RT = " + RT)
%             disp(" ")
%             disp(" ")
%             disp("________________________")


            %% Plotting
            % =======================================================================
%             set(0,'DefaultFigureWindowStyle','docked')
%             figure;
%             grid(app.UIAxes, 'on')
%             hold(app.UIAxes, 'on')
            % title('Case 1', 'FontSize', 14);
            % xlabel('Time(s)', 'FontSize', 14);
            % ylabel('Position (mm)', 'FontSize', 14);
            
            % plot tangency circle
            plot(app.UIAxes,xt,yt,'b')
            plot(app.UIAxes,xt,-yt,'b')
            axis equal
            
            % plot 200 points of crystal_C2 sphere and 75 points along crystal_C2 surface
            plot(app.UIAxes,x22,y22)
            plot(app.UIAxes,x2,y2,'g',Marker='.')
            plot(app.UIAxes,[xC2,xC2],[yC2,yC2],'r',Marker='.')
            
            % plot central point on Crystal_C2 and its real image of on tangency circle
            plot(app.UIAxes,[xC2,xC2],[yC2,yC2],'r',Marker='*')    % center of crystal_C2
            plot(app.UIAxes,[0,0],[RT,RT],'r',Marker='*')          % image of center of crystal_C2
            
            % plot center point of crystal_C1, point source S and its image I
            plot(app.UIAxes,[xC1,xC1],[yC1,yC1],'r',Marker='*')
            plot(app.UIAxes,[xS,xS],[yS,yS],'r',Marker='*')
            plot(app.UIAxes,[xI,xI],[yI,yI],'r',Marker='*')
            
            % plot, 200 points of crystal_C1 sphere and 75 points along crystal_C surface
            plot(app.UIAxes,x11,y11)
            plot(app.UIAxes,x1,y1,'g',Marker='.')
            plot(app.UIAxes,[xC1,xC1],[yC1,yC1],'r',Marker='.')
            
            % plot reflected ray from center of Crystal_C2 to its real image on tangency circle
            plot(app.UIAxes,[0,xC2],[RT,RT],'r',LineStyle="--")
            
            % plot incident ray on center of Crystal_C2
            plot(app.UIAxes,[xS,xC2],[yS,yC2],'r',LineStyle="--")
%             animate(xS,xC2, yS,yC2)
            
            % plot reflected ray from crystal_C2 to crystal_C1
            plot(app.UIAxes,[xC2,xC1],[yC2,yC1],'r',LineStyle="--")
%             animate(xC2,xC1, yC2,yC1)
            
            % plot reflected ray from crystal_C1 to image point I
            plot(app.UIAxes,[xC1,xI],[yC1,yI],'r',LineStyle="--")
%             animate(xC1,xI, yC1,yI)
            
            % length of incident ray from source S to crystal_C2 in presentstandard configuration
            p2 = -R2*sin(thet2)/cos(2*thet2);
            
            % length of reflected ray from crystal_C2 to image IC2 in present standard configuration	
            q2 = R2*sin(thet2);		


        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.energy = 8.04778d0;  % Energy in keV of Cu_k_alpha line
            app.EnergyEditField.Value = app.energy; 

            app.vex2D = 2.21707;
            app.ConvexCrystal2DspacingEditField.Value = app.vex2D;
            app.vexorder = 1;
            app.ConvexOrderEditField.Value = app.vexorder;
            
            app.cave2D = 6.7080;
            app.ConcaveCrystal2DspacingEditField.Value = app.cave2D;
            app.caveorder = 4;
            app.ConcaveOrderEditField.Value = app.caveorder;
            
            app.rad2 = 910.1991d0;
            app.ConcaveCrystalRadiusEditField.Value = app.rad2;

            app.a = 0d0;
            app.AlphaAngleEditField.Value = app.a;

            grid(app.UIAxes, 'on')
            hold(app.UIAxes, 'on')
            axis(app.UIAxes, 'equal')

        end

        % Value changed function: EnergyEditField
        function EnergyEditFieldValueChanged(app, event)
            app.energy = app.EnergyEditField.Value;
       
        end

        % Value changed function: ConvexCrystal2DspacingEditField
        function ConvexCrystal2DspacingEditFieldValueChanged(app, event)
            app.cave2D = app.ConvexCrystal2DspacingEditField.Value;
            
        end

        % Value changed function: ConvexOrderEditField
        function ConvexOrderEditFieldValueChanged(app, event)
            app.vexorder = app.ConvexOrderEditField.Value;
            
        end

        % Value changed function: ConcaveCrystal2DspacingEditField
        function ConcaveCrystal2DspacingEditFieldValueChanged(app, event)
            app.vex2D = app.ConcaveCrystal2DspacingEditField.Value;
            
        end

        % Value changed function: ConcaveOrderEditField
        function ConcaveOrderEditFieldValueChanged(app, event)
            app.caveorder = app.ConcaveOrderEditField.Value;
            
        end

        % Value changed function: ConcaveCrystalRadiusEditField
        function ConcaveCrystalRadiusEditFieldValueChanged(app, event)
            app.rad2 = app.ConcaveCrystalRadiusEditField.Value;
            
        end

        % Value changed function: AlphaAngleEditField
        function AlphaAngleEditFieldValueChanged(app, event)
            app.a = app.AlphaAngleEditField.Value;
            
        end

        % Value changed function: AlphaAngleSlider
        function AlphaAngleSliderValueChanged(app, event)
            app.a = app.AlphaAngleSlider.Value;
            app.calc;
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.calc;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ConcaveCrystal2DspacingEditFieldLabel = uilabel(app.UIFigure);
            app.ConcaveCrystal2DspacingEditFieldLabel.Text = 'Concave Crystal 2D-spacing';
            app.ConcaveCrystalRadiusEditFieldLabel = uilabel(app.UIFigure);
            app.ConcaveCrystalRadiusEditFieldLabel.Text = 'Concave Crystal Radius';
            app.ConvexCrystal2DspacingEditFieldLabel = uilabel(app.UIFigure);
            app.ConvexCrystal2DspacingEditFieldLabel.Text = 'Convex Crystal 2D-spacing';
            app.ConcaveOrderEditFieldLabel = uilabel(app.UIFigure);
            app.ConcaveOrderEditFieldLabel.Text = 'Concave Order';
            app.OutputTextArea = uitextarea(app.UIFigure);
            app.Lamp = uilamp(app.UIFigure);
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot!';
            app.AlphaAngleSlider = uislider(app.UIFigure);
            app.AlphaAngleSlider.Limits = [-100 100];
            app.AlphaAngleSlider.MajorTicks = [-100 -80 -60 -40 -20 0 20 40 60 80 100];
            app.AlphaAngleSlider.ValueChangedFcn = createCallbackFcn(app, @AlphaAngleSliderValueChanged, true);
            app.AlphaAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.AlphaAngleEditField.ValueChangedFcn = createCallbackFcn(app, @AlphaAngleEditFieldValueChanged, true);
            app.ConcaveCrystalRadiusEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConcaveCrystalRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @ConcaveCrystalRadiusEditFieldValueChanged, true);
            app.ConcaveOrderEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConcaveOrderEditField.ValueChangedFcn = createCallbackFcn(app, @ConcaveOrderEditFieldValueChanged, true);
            app.ConcaveCrystal2DspacingEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConcaveCrystal2DspacingEditField.ValueChangedFcn = createCallbackFcn(app, @ConcaveCrystal2DspacingEditFieldValueChanged, true);
            app.ConvexOrderEditFieldLabel = uilabel(app.UIFigure);
            app.ConvexOrderEditFieldLabel.Text = 'Convex Order';
            app.ConvexOrderEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConvexOrderEditField.ValueChangedFcn = createCallbackFcn(app, @ConvexOrderEditFieldValueChanged, true);
            app.ConvexCrystal2DspacingEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConvexCrystal2DspacingEditField.ValueChangedFcn = createCallbackFcn(app, @ConvexCrystal2DspacingEditFieldValueChanged, true);
            app.EnergyEditFieldLabel = uilabel(app.UIFigure);
            app.EnergyEditFieldLabel.Text = 'Energy';
            app.EnergyEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnergyEditField.ValueChangedFcn = createCallbackFcn(app, @EnergyEditFieldValueChanged, true);
            app.AlphaAngleEditFieldLabel = uilabel(app.UIFigure);
            app.AlphaAngleEditFieldLabel.Text = 'Alpha Angle';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.OutputTextAreaLabel = uilabel(app.UIFigure);
            app.OutputTextAreaLabel.Text = 'Output';
            app.AlphaAngleSliderLabel = uilabel(app.UIFigure);
            app.AlphaAngleSliderLabel.Text = 'Alpha Angle Slider';
            app.DCILabel = uilabel(app.UIFigure);
            app.DCILabel.Text = 'DCI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DCI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end